classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        HTML      matlab.ui.control.HTML
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        % Callback al recibir datos desde JS
        function HTMLEventReceived(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;

            % Aquí capturas los datos que JS te envía
            disp(['Evento recibido: ', eventName]);
            disp(eventData);
            
            % Si es imagen, haces el procesamiento (ejemplo: invertir)
            if isfield(eventData, 'imagen')
                base64String = eventData.imagen;
                imgBytes = matlab.net.base64decode(base64String);
                img = services.imdecode(imgBytes, 'jpg');

                
                [imgRes, numObjetos] = services.identificarObjetos(img);
              
                % Enviar la imagen invertida a JS
                app.enviarImagenAJS(imgRes);

                app.enviarDatosAJS(numObjetos);
            end
        end

        % Enviar datos desde MATLAB a JS (por CustomData)
        function enviarImagenAJS(app, img)

            disp('Enviando imagen');

            imgresult = services.imencode(img, 'jpg');
            imgresult = matlab.net.base64encode(imgresult);

            disp(imgresult)

            sendEventToHTMLSource(app.HTML, "ImagenProcesada", imgresult);
            
        end

        function enviarDatosAJS(app, data)
            disp('Enviando datos')

            sendEventToHTMLSource(app.HTML, "numeroDeObjetos", data);
        end

    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            services.training

            app.HTML.HTMLSource = fullfile(pwd, '+interface', 'index.html');
            app.HTML.HTMLEventReceivedFcn = createCallbackFcn(app, @HTMLEventReceived, true);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HTML = uihtml(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end